classdef display_handsignal_dynamic < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Label                 matlab.ui.control.Label
        LoadHandSignalButton  matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadHandSignalButton
        function GetFile(app, event)
            %% Read in JSON file
            [filename, pathname] = uigetfile('*.json', 'Select JSON data file');
            fullFilePath = fullfile(pathname, filename);

            fid = fopen(fullFilePath); % Open file
            raw = fread(fid, inf); % Read raw JSON data
            str = char(raw'); % Convert to character array
            fclose(fid); % Close the file
            data = jsondecode(str); % Decode JSON data

            %% Extract quaternion data from hand/arm/finger channels
            L = length(data.animations.channels(12).rotationkeys);
            i = 1; % index
            c = 0; % index
            rot_key = [];

            while i < 75
                rot_key_length = length(data.animations.channels(i).rotationkeys);
                if rot_key_length > 2
                    c = c + 1;
                    for sample = 1:L %
                        rot_num = (data.animations.channels(i).rotationkeys{sample,1}{2,1})';
                        rot_key(sample,1:4) = rot_num;
                    end
                    q = quaternion(rot_key(2:L,4),rot_key(2:L,1),rot_key(2:L,2),rot_key(2:L,3));
                    ang = angvel(q,1/30,"frame"); % angular velocity

                    segmentName = data.animations.channels(i).name;
                    movement.(segmentName).q = rot_key;
                    movement.(segmentName).w = ang;
                    movement.(segmentName).w_mag = vecnorm(movement.(segmentName).w,2,2);

                    clear rot_key
                    clear ang
                end
                i = i + 1;
            end

            %% Compile matrix of quaternion data
            segmentNames = fieldnames(movement);

            allq = [];
            allw = [];

            for j = 1:length(segmentNames)
                if j == 1
                    allq = movement.(char(segmentNames(j))).q;
                    allw = movement.(char(segmentNames(j))).w_mag;
                else
                    allq = [allq, movement.(char(segmentNames(j))).q];
                    allw = [allw, movement.(char(segmentNames(j))).w_mag];
                end
            end

            allq = allq(2:end, :); % remove first row of reference quats
            alldata = [allq, allw]; % first 88 columns are quat, next 66 columns are w

            %% Figure out if file is static or dynamic?
            avg_w = mean(allw(2:end,:));

            if avg_w < 1
                load('trainedModel_0to9_FINAL.mat');
                yfit = trainedModel_0to9_FINAL.predictFcn(allq); % Outputs yfit
                app.Label.FontSize = 90;
                app.Label.Text = num2str(mode(yfit));

            elseif avg_w < 2
                load('trainedModel_0to9_FINAL.mat');
                yfit = trainedModel_0to9_FINAL.predictFcn(allq); % Outputs yfit

                % Use findblocks function to determine series of numbers
                [block_ind, block_length] = findblocks(gradient(yfit)==0);
                kk = 1;
                for jj = 1:length(block_length)
                    if block_length(jj) >= 20
                        sequence(kk) = mode(yfit(block_ind(jj,1):block_ind(jj,2)));
                        kk = kk + 1;
                    else
                    end
                end
                app.Label.FontSize = 80;
                app.Label.Text = num2str(sequence);

            else
                load('trainedModel_dynamic_FINAL.mat');
                yfit_d = trainedModel_dynamic_FINAL.predictFcn(alldata); % Outputs yfit

                % Use assigned numerical values to show word signal
                if yfit_d(1) == 10
                    app.Label.FontSize = 60;
                    app.Label.Text = 'Rally Point';
                elseif yfit_d(1) == 11
                    app.Label.FontSize = 60;
                    app.Label.Text = 'Move Up';
                elseif yfit_d(1) == 12
                    app.Label.FontSize = 30;
                    app.Label.Text = 'Column Formation';
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LoadHandSignalButton = uibutton(app.UIFigure, 'push');
            app.LoadHandSignalButton.ButtonPushedFcn = createCallbackFcn(app, @GetFile, true);
            app.LoadHandSignalButton.Text = 'Load Hand Signal';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {'...'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = display_handsignal_dynamic

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end